classdef matlab_basic_plotting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Plot3dAxes              matlab.ui.control.UIAxes
        CreateShapeButton       matlab.ui.control.Button
        CreateSourceButton      matlab.ui.control.Button
        TraceButton             matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        AddButton               matlab.ui.control.Button
        TriangleEditFieldLabel  matlab.ui.control.Label
        TriangleEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        temp_cm % Description
        p % Description
    end
    
    methods (Access = private)
        
        function plotshape(app,p,cm)
            [numtri,~] = size(cm);
            for tri = 1:numtri
                ptri(:,1) = p(cm(tri,1)+1,:);
                ptri(:,2) = p(cm(tri,2)+1,:);
                ptri(:,3) = p(cm(tri,3)+1,:);
                ptri(:,4) = p(cm(tri,1)+1,:);
                x=ptri(1,:);
                y=ptri(2,:);
                z=ptri(3,:);
                line(app.Plot3dAxes,x,y,z)
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p = [];
            TraceButtonPushed(app,0)
        end

        % Button pushed function: TraceButton
        function TraceButtonPushed(app, event)
%             cm = app.temp_cm;
%             p=app.p;
%             save("python_inputs.mat",'cm',"p")
            %pyrunfile doesn't exist in this matlab version
%             if class(inputdlg("Please run python program then press ok"))=='cell'
%             end
            load("python_outputs.mat");
            
            dacc(dacc==0)=40; % extend rays
            
            rdacc = repmat(dacc,3,1)';
            dir = upacc.*rdacc;
            
            quiver3(app.Plot3dAxes,pacc(:,1),pacc(:,2),pacc(:,3),dir(:,1),dir(:,2),dir(:,3),0)
            
            plotshape(app,sp,cm)
            axis(app.Plot3dAxes,'equal')
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.p(:,end+1) = [app.XEditField.Value,...
                app.YEditField.Value,...
                app.ZEditField.Value];
            s = size(app.p);
            app.temp_cm(app.TriangleEditField.Value,mod(s(2)-1,3)+1) = s(2)-1;
            scatter3(app.Plot3dAxes,app.p(1,:),app.p(2,:),app.p(3,:))
            hold(app.Plot3dAxes,"on")
        end

        % Button pushed function: CreateShapeButton
        function CreateShapeButtonPushed(app, event)
            plotshape(app,app.p.',app.temp_cm)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Plot3dAxes = uiaxes(app.UIFigure);
            title(app.Plot3dAxes, 'Plot 3D')
            xlabel(app.Plot3dAxes, 'X')
            ylabel(app.Plot3dAxes, 'Y')
            app.CreateShapeButton = uibutton(app.UIFigure, 'push');
            app.CreateShapeButton.ButtonPushedFcn = createCallbackFcn(app, @CreateShapeButtonPushed, true);
            app.CreateShapeButton.Text = 'Create Shape';
            app.CreateSourceButton = uibutton(app.UIFigure, 'push');
            app.CreateSourceButton.Text = 'Create Source';
            app.TraceButton = uibutton(app.UIFigure, 'push');
            app.TraceButton.ButtonPushedFcn = createCallbackFcn(app, @TraceButtonPushed, true);
            app.TraceButton.Text = 'Trace';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = {'Y'; ''};
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TriangleEditFieldLabel = uilabel(app.UIFigure);
            app.TriangleEditFieldLabel.Text = 'Triangle';
            app.TriangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TriangleEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_basic_plotting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end